classdef import_image < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasheetreaderUIFigure     matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        OpenimagefileButton         matlab.ui.control.Button
        DefineoriginButton          matlab.ui.control.Button
        DefinecurveButton           matlab.ui.control.Button
        XEditFieldLabel             matlab.ui.control.Label
        OriginX                     matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        OriginY                     matlab.ui.control.NumericEditField
        OriginLabel                 matlab.ui.control.Label
        MaxXYLabel                  matlab.ui.control.Label
        XEditField_2Label           matlab.ui.control.Label
        Xlimit                      matlab.ui.control.NumericEditField
        YEditField_2Label           matlab.ui.control.Label
        Ylimit                      matlab.ui.control.NumericEditField
        XaxisDropDownLabel          matlab.ui.control.Label
        XaxisDropDown               matlab.ui.control.DropDown
        YaxisDropDownLabel          matlab.ui.control.Label
        YaxisDropDown               matlab.ui.control.DropDown
        FittypeDropDownLabel        matlab.ui.control.Label
        FittypeDropDown             matlab.ui.control.DropDown
        CustomEditFieldLabel        matlab.ui.control.Label
        CustomEditField             matlab.ui.control.EditField
        StartfittingButton          matlab.ui.control.Button
        DefinemaxXYButton           matlab.ui.control.Button
        GoodnessoffitTextAreaLabel  matlab.ui.control.Label
        GoodnessoffitTextArea       matlab.ui.control.TextArea
        SavecurveButton             matlab.ui.control.Button
        SentforreviewtoIEEETransactionsonPowerElectronicsLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        image_loaded=0; % Description
        image_file; % Description
        origin_xy=[0 0];
        limit_xy=[0 0];
        origin_usr_def=[0 0];
        limit_usr_def=[0 0];
        xaxis_='linear';
        yaxis_='linear';
        fittype_='power1';
        customftype='';
        customfoptions='';
        curve=0;
        lastcurve='Curve1';
        lastout='Output';
        fitt=[];
        coeffs=0;
        xx_yy=[0; 0];
        goff=0;
        cache=[];
        
    end


    methods (Access = private)

        % Button pushed function: OpenimagefileButton
        function OpenimagefileButtonPushed(app, event)
            [file_, path_] = uigetfile({'*.bmp; *.BMP; *.jpg; *.JPG; *.jpeg; *.JPEG; *.img; *.IMG; *.tif; *.TIF; *.tiff, *.TIFF','Image Files (*.bmp,*.jpg,*.img,*.tiff,)'; ...
                                        '*.*',  'All Files (*.*)'});
            if file_==0
                % No file was selected
                return
            end
            kuva = imread(fullfile(path_,file_));
            app.image_file=kuva;
            app.image_loaded=1;
            hold(app.UIAxes,'off')
            cla(app.UIAxes);
            imshow(kuva,'Parent',app.UIAxes);
            hold(app.UIAxes,'on')
            
            app.cache=[];
            app.fitt=[];
            app.coeffs=0;
            
        end

        % Button pushed function: DefineoriginButton
        function DefineoriginButtonPushed(app, event)
            if app.image_loaded==0
                
                msgbox('No image file','Error','error')
                return
                
            else
                
                %msgbox('Select origin','Attention','help')
                
                fig = figure(1);
                imshow(app.image_file);
               
                [xo, yo]=getpts(fig);
                app.origin_xy=round([xo yo]);
                %assignin('base','origin',app.origin_xy);
                
                plot(app.UIAxes,app.origin_xy(1),app.origin_xy(2),'m*','MarkerSize',10);
                
                close(fig)
                
            end
        end

        % Button pushed function: DefinecurveButton
        function DefinecurveButtonPushed(app, event)
            if app.image_loaded==0
                
                msgbox('No image file','Error','error')
                return
                
            else
                
                %msgbox('Define curve','Attention','help')
                
                fig = figure(1);
                imshow(app.image_file);
               
                [xi, yi]=getline(fig);
                app.curve=round([xi, yi]);
                
                plot(app.UIAxes,app.curve(:,1),app.curve(:,2),'--*','MarkerSize',10);
                

                close(fig)
                
            end
        end

        % Value changed function: OriginX
        function OriginXValueChanged(app, event)
            value = app.OriginX.Value;
            app.origin_usr_def(1,1)=value;
        end

        % Value changed function: OriginY
        function OriginYValueChanged(app, event)
            value = app.OriginY.Value;
            app.origin_usr_def(1,2)=value;
        end

        % Value changed function: Xlimit
        function XlimitValueChanged(app, event)
            value = app.Xlimit.Value;
            app.limit_usr_def(1,1)=value;
        end

        % Value changed function: Ylimit
        function YlimitValueChanged(app, event)
            value = app.Ylimit.Value;
            app.limit_usr_def(1,2)=value;
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            app.xaxis_ = value;
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            app.yaxis_ = value;
        end

        % Button pushed function: StartfittingButton
        function StartfittingButtonPushed(app, event)
            if app.image_loaded==0
                
                msgbox('No image file','Error','error')
                return
                
            elseif app.origin_usr_def==app.limit_usr_def
                
                msgbox('Invalid axes parameters','Error','error')
                return
                
            elseif app.curve==0
                
                msgbox('Curve not defined','Error','error')
                return
                
            else
                % Read data from the image and scale it according to the user defined axes limits
                
                x_ind = app.curve(:,1);
                    v = app.curve(:,2);
                    
                if strcmp(app.xaxis_,'linear')==1 && strcmp(app.yaxis_,'linear')==1
                    
                    %X(1): app.origin_usr_def(1)
                    %X(2): app.limit_usr_def(1)
                    %Y(1): app.origin_usr_def(2)
                    %Y(2): app.limit_usr_def(2)
                    
                    %x_ind2(1): app.origin_xy(1)
                    %x_ind2(2): app.limit_xy(1)
                    %v2(1)    : app.origin_xy(2)
                    
                    Xstep = (app.limit_usr_def(1) - app.origin_usr_def(1)) / (app.limit_xy(1) - app.origin_xy(1));
                    Ystep = (app.limit_usr_def(2) - app.origin_usr_def(2)) / (app.origin_xy(2) - app.limit_xy(2));
                    
                    XX=zeros(1,length(x_ind));
                    YY=zeros(1,length(v));
                    
                    XX=(x_ind-app.origin_xy(1))';
                    YY=(app.origin_xy(2)-v)';
                    
                    if app.origin_usr_def(1)==0
                        XX=XX.*Xstep;
                    else
                        XX=XX.*Xstep+app.origin_usr_def(1);
                    end
                    
                    if app.origin_usr_def(2)==0
                        YY=YY.*Ystep;
                    else
                        YY=YY.*Ystep+app.origin_usr_def(2);
                    end
                    
                    figtype=1;
                    
                elseif strcmp(app.xaxis_,'logarithmic')==1 && strcmp(app.yaxis_,'linear')==1
                    
                    Xvector=logspace(log10(app.origin_usr_def(1)),log10(app.limit_usr_def(1)),(app.limit_xy(1)-app.origin_xy(1)));
                    Ystep=(app.limit_usr_def(2) - app.origin_usr_def(2)) / (app.origin_xy(2) - app.limit_xy(2));
                    
                    XX=zeros(1,length(x_ind));
                    YY=zeros(1,length(v));
                    
                    XX=(x_ind-app.origin_xy(1))';
                    YY=(app.origin_xy(2)-v)';
                    
                    XX=Xvector(XX);
                    
                    if app.origin_usr_def(2)==0
                        YY=YY.*Ystep;
                    else
                        YY=YY.*Ystep+app.origin_usr_def(2);
                    end
                    
                    figtype=2;
                    
                elseif strcmp(app.xaxis_,'linear')==1 && strcmp(app.yaxis_,'logarithmic')==1
                    
                    %X(1): app.origin_usr_def(1)
                    %X(2): app.limit_usr_def(1)
                    %Y(1): app.origin_usr_def(2)
                    %Y(2): app.limit_usr_def(2)
                    
                    %x_ind2(1): app.origin_xy(1)
                    %x_ind2(2): app.limit_xy(1)
                    %v2(1)    : app.origin_xy(2)
                    %v2(2)    : app.limit_xy(2)
                    
                    Yvector=logspace(log10(app.origin_usr_def(2)),log10(app.limit_usr_def(2)),-(app.limit_xy(2)-app.origin_xy(2)));
                    
                    Xstep=( (app.limit_usr_def(1)-app.origin_usr_def(1)) / (app.limit_xy(1)-app.origin_xy(1)) );
                    
                    XX=zeros(1,length(x_ind));
                    YY=zeros(1,length(v));
                    
                    XX=(x_ind-app.origin_xy(1))';
                    YY=(app.origin_xy(2)-v)';
                    
                    YY=Yvector(YY);
                    
                    if app.origin_usr_def(1)==0
                        XX=XX.*Xstep;
                    else
                        XX=XX.*Xstep+app.origin_usr_def(1);
                    end
                    
                    figtype=3;
                    
                elseif strcmp(app.xaxis_,'logarithmic')==1 && strcmp(app.yaxis_,'logarithmic')==1
                    
                    Xvector=logspace(log10(app.origin_usr_def(1)),log10(app.limit_usr_def(1)),(app.limit_xy(1)-app.origin_xy(1)));
                    Yvector=logspace(log10(app.origin_usr_def(2)),log10(app.limit_usr_def(2)),-(app.limit_xy(2)-app.origin_xy(2)));
                    
                    XX=zeros(1,length(x_ind));
                    YY=zeros(1,length(v));
                    
                    XX=(x_ind-app.origin_xy(1))';
                    YY=(app.origin_xy(2)-v)';
                    
                    YY=Yvector(YY);
                    XX=Xvector(XX);
                    
                    figtype=4;
                    
                end

                if strcmp(app.fittype_,'custom')==0 && strcmp(app.fittype_,'4th order Foster')==0

                    [curve_fit, gof] = fit(XX',YY',app.fittype_);
                    fit_coef = coeffvalues(curve_fit);

                    %assignin('base','fit_coeffs',fit_coef);
                    %assignin('base','curve_xy',[XX; YY]);
                    %assignin('base','goodness',gof);
                    
                    app.fitt=curve_fit;
                    app.coeffs=fit_coef;
                    app.xx_yy=[XX; YY];
                    app.goff=gof;
                    
                    app.GoodnessoffitTextArea.Value = {['SSE:',num2str(gof.sse)]; ['R-square:',num2str(gof.rsquare)]; ...
                                          ['Adjusted R-square:',num2str(gof.adjrsquare)]; ['RMSE:',num2str(gof.rmse)]};
                    
                    fig2 = figure(2);
                    
                    if figtype==1
                        
                        plot(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        plot((0.1:0.1:XX(end)*1.2),curve_fit(0.1:0.1:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
  
                    elseif figtype==2
                        
                        semilogx(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        semilogx(fig,(0.1:0.1:XX(end)*1.2),curve_fit(0.1:0.1:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        
                    elseif figtype==3
                        
                        semilogy(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        semilogy((0.1:0.1:XX(end)*1.2),curve_fit(0.1:0.1:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        
                    elseif figtype==4
                        
                        loglog(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        loglog((0.1:0.1:XX(end)*1.2),curve_fit(0.1:0.1:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        
                    end
                    
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %
                %           4th order Foster fit   
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     
                
                elseif strcmp(app.fittype_,'4th order Foster')==1
                
                    counter = 0;
                    check = 0;
                    
                    while check < 0.999 && counter < 20
                        
                        [curve_fit, gof] = fit(XX',YY',fittype('a*(1-exp(-x/b)) + c*(1-exp(-x/d)) + e*(1-exp(-x/f)) + g*(1-exp(-x/h))'), ...
                            fitoptions('Method','NonlinearLeastSquares','Algorithm','Levenberg-Marquardt'));
                        fit_coef = coeffvalues(curve_fit);
                        check=gof.adjrsquare;
                        counter=counter+1;
                    
                    end

                    %assignin('base','fit_coeffs',fit_coef);
                    %assignin('base','curve_xy',[XX; YY]);
                    %assignin('base','goodness',gof);
                    
                    app.fitt=curve_fit;
                    app.coeffs=fit_coef;
                    app.xx_yy=[XX; YY];
                    app.goff=gof;
                    
                    app.GoodnessoffitTextArea.Value = {['SSE:',num2str(gof.sse)]; ['R-square:',num2str(gof.rsquare)]; ...
                                          ['Adjusted R-square:',num2str(gof.adjrsquare)]; ['RMSE:',num2str(gof.rmse)]};
                    
                    fig2 = figure(2);
                    
                    if figtype==1
                        
                        plot(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        plot((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
  
                    elseif figtype==2
                        
                        semilogx(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        semilogx((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        
                    elseif figtype==3
                        
                        semilogy(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        semilogy((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        
                    elseif figtype==4
                        
                        loglog(XX,YY,'*r','MarkerSize',10);

                        hold('on')
                        loglog((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        grid('on')
                    end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %
                %           Custom fit    
                %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
                
                elseif strcmp(app.fittype_,'custom')==1
                    
                    if ~isempty(app.customftype)
                        
                    [curve_fit, gof] = fit(XX',YY',fittype(app.customftype));
                    
                    else
                        msgbox('Not enough input arguments','Error','error')
                        return
                    end                               
                                                   
                    fit_coef = coeffvalues(curve_fit);

                    %assignin('base','fit_coeffs',fit_coef);
                    %assignin('base','curve_xy',[XX; YY]);
                    %assignin('base','goodness',gof);
                    
                    app.fitt=curve_fit;
                    app.coeffs=fit_coef;
                    app.xx_yy=[XX; YY];
                    app.goff=gof;
                    
                    app.GoodnessoffitTextArea.Value = {['SSE:',num2str(gof.sse)]; ['R-square:',num2str(gof.rsquare)]; ...
                                          ['Adjusted R-square:',num2str(gof.adjrsquare)]; ['RMSE:',num2str(gof.rmse)]};
                    
                    fig2 = figure(2);
                    
                    if figtype==1
                        
                        plot(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        plot((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
  
                    elseif figtype==2
                        
                        semilogx(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        semilogx((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        
                    elseif figtype==3
                        
                        semilogy(XX,YY,'*r','MarkerSize',10);
                        grid('on')
                        hold('on')
                        semilogy((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        
                    elseif figtype==4
                        
                        loglog(XX,YY,'*r','MarkerSize',10);

                        hold('on')
                        loglog((app.origin_usr_def(1):0.001:XX(end)*1.2),curve_fit(app.origin_usr_def(1):0.001:XX(end)*1.2))
                        axis([app.origin_usr_def(1) app.limit_usr_def(1) app.origin_usr_def(2) app.limit_usr_def(2)])
                        grid('on')
                    end    
                    
                else
                    msgbox('Select another fit function','Error','error') % For testing only
                    return
                end
                
            end
                
        end

        % Value changed function: FittypeDropDown
        function FittypeDropDownValueChanged(app, event)
            value = app.FittypeDropDown.Value;
            app.fittype_=value;
            %msgbox(value) % test
        end

        % Button pushed function: DefinemaxXYButton
        function DefinemaxXYButtonPushed(app, event)
            
            fig = figure(1);
                imshow(app.image_file);
                               
                %msgbox('Select point for max X/Y','Attention','help')
                
                [xm, ym]=getpts(fig);
                app.limit_xy=round([xm ym]);
                %assignin('base','max_xy',app.limit_xy);
                
                plot(app.UIAxes,app.limit_xy(1),app.limit_xy(2),'m*','MarkerSize',10);
                
                close(fig)
        end

        % Value changed function: CustomEditField
        function CustomEditFieldValueChanged(app, event)
            value = app.CustomEditField.Value;
            app.customftype = value;
        end

        % Button pushed function: SavecurveButton
        function SavecurveButtonPushed(app, event)
            
            if app.image_loaded==0
                
                msgbox('No image file','Error','error')
                return
                
            elseif app.origin_usr_def==app.limit_usr_def
                
                msgbox('Invalid axes parameters','Error','error')
                return
                
            elseif app.curve==0
                
                msgbox('Curve not defined','Error','error')
                return
                
            elseif isempty(app.fitt)
                
                msgbox('Fit not calculated','Error','error')
                return
                
            else
                
                % Save data
                
                if strcmp(app.lastcurve,'Curve1')==1
                    curvename='Curve1';
                else
                    curvename=app.lastcurve;
                end
                
                if strcmp(app.lastout,'Output')==1
                    outputname='Output';
                else
                    outputname=app.lastout;
                end
                
                prompt = {'Enter output variable name:', 'Enter curve name:'};
                dlgtitle = 'Save as...';
                dims = [1 35];
                definput = {outputname, curvename};
                answer = inputdlg(prompt,dlgtitle,dims,definput);
                
                if length(answer)==2 && isempty(answer{1})==0 && isempty(answer{2})==0
                    
                    if isempty(app.cache)
                        
                        fieldname = regexprep(answer{2},{'\W'},''); % Remove special characters to generate a valid fieldname
                        output = regexprep(answer{1},{'\W'},''); % Remove special characters to generate a valid fieldname
                        
                        if isempty(fieldname)==1 || strcmp(fieldname(1),'_')==1
                            
                            msgbox('Invalid curve name','Error','error')
                            return
                            
                        elseif isempty(output)==1 || strcmp(output(1),'_')==1
                            
                            msgbox('Invalid output variable name','Error','error')
                            return
                            
                        else
                            
                            Curves.data.(fieldname) = app.xx_yy; % Curve points
                            Curves.fit.(fieldname) = app.fitt; % Fit functions (cfit)
                            Curves.coeff.(fieldname) = app.coeffs;  % Fit coefficients
                            Curves.gof.(fieldname) = app.goff;       % Goodness of fit
                            
                            app.lastout=answer{1};
                            app.lastcurve=answer{2};
                            
                            app.cache=Curves;
                            
                            assignin('base',output,Curves);
                            
                        end
                    else
                        
                        fieldname = regexprep(answer{2},{'\W'},''); % Remove special characters to generate a valid fieldname
                        output = regexprep(answer{1},{'\W'},''); % Remove special characters to generate a valid fieldname
                        
                        if isempty(fieldname)==1 || strcmp(fieldname(1),'_')==1
                            
                            msgbox('Invalid curve name','Error','error')
                            return
                            
                        elseif isempty(output)==1 || strcmp(output(1),'_')==1
                            
                            msgbox('Invalid output variable name','Error','error')
                            return
                            
                        else
                            
                            Curves = app.cache;
                            
                            Curves.data.(fieldname) = app.xx_yy; % Curve points
                            Curves.fit.(fieldname) = app.fitt; % Fit functions (cfit)
                            Curves.coeff.(fieldname) = app.coeffs;  % Fit coefficients
                            Curves.gof.(fieldname) = app.goff;       % Goodness of fit
                            
                            app.lastout=answer{1};
                            app.lastcurve=answer{2};
                            
                            app.cache=Curves;
                            
                            assignin('base',output,Curves);
                            
                        end
                        
                    end
                    
                elseif length(answer)>=1 && isempty(answer{1})==1
                    
                    msgbox('Please enter an output variable name','Error','error')
                    return
                    
                elseif length(answer)==2 && isempty(answer{2})==1
                    
                    msgbox('Please enter a curve name','Error','error')
                    return
                else
                    
                    return
                    
                end
                
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatasheetreaderUIFigure = uifigure;
            app.DatasheetreaderUIFigure.Name = 'Datasheet reader';
            app.UIAxes = uiaxes(app.DatasheetreaderUIFigure);
            title(app.UIAxes, 'Imported image file')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.OpenimagefileButton = uibutton(app.DatasheetreaderUIFigure, 'push');
            app.OpenimagefileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenimagefileButtonPushed, true);
            app.OpenimagefileButton.Text = 'Open image file';
            app.DefineoriginButton = uibutton(app.DatasheetreaderUIFigure, 'push');
            app.DefineoriginButton.ButtonPushedFcn = createCallbackFcn(app, @DefineoriginButtonPushed, true);
            app.DefineoriginButton.Text = 'Define origin';
            app.DefinecurveButton = uibutton(app.DatasheetreaderUIFigure, 'push');
            app.DefinecurveButton.ButtonPushedFcn = createCallbackFcn(app, @DefinecurveButtonPushed, true);
            app.DefinecurveButton.Text = 'Define curve';
            app.XEditFieldLabel = uilabel(app.DatasheetreaderUIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.OriginX = uieditfield(app.DatasheetreaderUIFigure, 'numeric');
            app.OriginX.ValueChangedFcn = createCallbackFcn(app, @OriginXValueChanged, true);
            app.YEditFieldLabel = uilabel(app.DatasheetreaderUIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.OriginY = uieditfield(app.DatasheetreaderUIFigure, 'numeric');
            app.OriginY.ValueChangedFcn = createCallbackFcn(app, @OriginYValueChanged, true);
            app.OriginLabel = uilabel(app.DatasheetreaderUIFigure);
            app.OriginLabel.Text = 'Origin';
            app.MaxXYLabel = uilabel(app.DatasheetreaderUIFigure);
            app.MaxXYLabel.Text = 'Max X/Y';
            app.XEditField_2Label = uilabel(app.DatasheetreaderUIFigure);
            app.XEditField_2Label.Text = 'X';
            app.Xlimit = uieditfield(app.DatasheetreaderUIFigure, 'numeric');
            app.Xlimit.ValueChangedFcn = createCallbackFcn(app, @XlimitValueChanged, true);
            app.YEditField_2Label = uilabel(app.DatasheetreaderUIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.Ylimit = uieditfield(app.DatasheetreaderUIFigure, 'numeric');
            app.Ylimit.ValueChangedFcn = createCallbackFcn(app, @YlimitValueChanged, true);
            app.XaxisDropDownLabel = uilabel(app.DatasheetreaderUIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.DatasheetreaderUIFigure);
            app.XaxisDropDown.Items = {'linear', 'logarithmic'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'linear';
            app.YaxisDropDownLabel = uilabel(app.DatasheetreaderUIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.DatasheetreaderUIFigure);
            app.YaxisDropDown.Items = {'linear', 'logarithmic'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 'linear';
            app.FittypeDropDownLabel = uilabel(app.DatasheetreaderUIFigure);
            app.FittypeDropDownLabel.Text = 'Fit type';
            app.FittypeDropDown = uidropdown(app.DatasheetreaderUIFigure);
            app.FittypeDropDown.Items = {'power1', 'power2', 'poly1', 'poly2', 'poly3', 'poly4', 'poly5', 'poly6', 'exp1', 'exp2', '4th order Foster', 'custom'};
            app.FittypeDropDown.ValueChangedFcn = createCallbackFcn(app, @FittypeDropDownValueChanged, true);
            app.FittypeDropDown.Value = 'power1';
            app.CustomEditFieldLabel = uilabel(app.DatasheetreaderUIFigure);
            app.CustomEditFieldLabel.Text = 'Custom';
            app.CustomEditField = uieditfield(app.DatasheetreaderUIFigure, 'text');
            app.CustomEditField.ValueChangedFcn = createCallbackFcn(app, @CustomEditFieldValueChanged, true);
            app.StartfittingButton = uibutton(app.DatasheetreaderUIFigure, 'push');
            app.StartfittingButton.ButtonPushedFcn = createCallbackFcn(app, @StartfittingButtonPushed, true);
            app.StartfittingButton.Text = 'Start fitting';
            app.DefinemaxXYButton = uibutton(app.DatasheetreaderUIFigure, 'push');
            app.DefinemaxXYButton.ButtonPushedFcn = createCallbackFcn(app, @DefinemaxXYButtonPushed, true);
            app.DefinemaxXYButton.Text = 'Define max X/Y';
            app.GoodnessoffitTextAreaLabel = uilabel(app.DatasheetreaderUIFigure);
            app.GoodnessoffitTextAreaLabel.Text = 'Goodness of fit';
            app.GoodnessoffitTextArea = uitextarea(app.DatasheetreaderUIFigure);
            app.GoodnessoffitTextArea.Editable = 'off';
            app.SavecurveButton = uibutton(app.DatasheetreaderUIFigure, 'push');
            app.SavecurveButton.ButtonPushedFcn = createCallbackFcn(app, @SavecurveButtonPushed, true);
            app.SavecurveButton.Text = 'Save curve';
            app.SentforreviewtoIEEETransactionsonPowerElectronicsLabel = uilabel(app.DatasheetreaderUIFigure);
            app.SentforreviewtoIEEETransactionsonPowerElectronicsLabel.Text = 'Sent for review to IEEE Transactions on Power Electronics. Copyright: A. Mattsson and V. Tikka. CC-BY 4.0';
        end
    end

    methods (Access = public)

        % Construct app
        function app = import_image

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasheetreaderUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasheetreaderUIFigure)
        end
    end
end